classdef Latest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        GridTab                   matlab.ui.container.Tab
        ListenerTrajectoryButton  matlab.ui.control.Button
        PLAYButton                matlab.ui.control.Button
        Y1Slider                  matlab.ui.control.Slider
        Y1SliderLabel             matlab.ui.control.Label
        X1Slider                  matlab.ui.control.Slider
        X1SliderLabel             matlab.ui.control.Label
        ListenerCoordinatesLabel  matlab.ui.control.Label
        SoundsourceLabel          matlab.ui.control.Label
        UIAxes_2                  matlab.ui.control.UIAxes
        Tab                       matlab.ui.container.Tab
        ListenerElevLabel         matlab.ui.control.Label
        SoundSrcElevLabel         matlab.ui.control.Label
        ListenerElevationSlider   matlab.ui.control.Slider
        El1Slider                 matlab.ui.control.Slider
        ElevationsLabel           matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

      properties (Access = private)
        s1x=0
        s1y=0
        s4x=0
        s4y=0
        el1z=0
        el4z=0 % Description
        roi % Description
        coordinates=0 % Description
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        
      function [x, y] = selectDataPoints(~, ax)
    roi = drawpolyline(ax,'Color','blue');
    x = roi.Position(1);
    y = roi.Position(2);
       end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
           
            app.s1x=app.X1Slider.Value;
            app.s1y=app.Y1Slider.Value;
            app.el1z=app.El1Slider.Value;
            app.el4z=app.ListenerElevationSlider.Value;


%--------------------------------------------------------------------------------------------      
%%
newcol=[app.el4z;app.el4z;app.el4z;app.el4z;app.el4z];
app.coordinates=[app.coordinates newcol];
%Room Impulse Response
    c = 340;                    % Sound velocity (m/s)
    fs = 16000;                 % Sample frequency (samples/s)
    r1 = app.coordinates(1,:);        % Receiver position [x y z] (m)
    r2=app.coordinates(2,:);
    r3=app.coordinates(3,:);
    r4=app.coordinates(4,:);   
    r5=app.coordinates(5,:);
    s = [app.s1x app.s1y app.el1z]; % Source position [x y z] (m)
    L = [2 2 1];                % Room dimensions [x y z] (m)
    beta = 0.4;                 % Reverberation time (s)
    n = 4096;                   % Number of samples

[h1] = rir_generator(c, fs, r1, s, L, beta, n);
[h2] = rir_generator(c, fs, r2, s, L, beta, n);
[h3] = rir_generator(c, fs, r3, s, L, beta, n);
[h4] = rir_generator(c, fs, r4, s, L, beta, n);
[h5] = rir_generator(c, fs, r5, s, L, beta, n);
app.coordinates(:,3)=[];
%plot(h);
% Reading sound source file
x=audioread('Chimes.wav');
%subplot(2,2,1);
%plot(x);
%title('Original Sound');
for n=1:5                   %% This is where the iterations for 5 different listener locations begin
    if n==1
        [h]=h1;
    elseif n==2
        [h]=h2;
    elseif n==3
        [h]=h3;
    elseif n==4
        [h]=h4;
    else 
        [h]=h5;
    end
    subplot(3,1,1)
    plot(h);
    title('Generated RIR');
    [y]=filter(h,1,x);
    azimuth1=atand(app.s1x/app.el1z);
    azimuth2=atand(app.coordinates(n,1)/app.el4z);
    azimuth=azimuth1-azimuth2;
    elevation1=atand(app.el1z/app.s1y);
    elevation2=atand(app.el4z/app.coordinates(n,2));
    elevation=elevation1-elevation2;
    %% 
    %Calculating closest sample elevations
    elevation=pvaldeg(elevation);
    elmax=50;
    elindices=1:elmax;
    def_elevs= -45+5.625*(elindices-1);
    [ele_closest1,nelev]=min(abs(def_elevs-elevation));            % nelev=Index number of selected elevation
    ele_closest1=def_elevs(nelev);
    if ele_closest1<=elevation
        if nelev==50
            ele_closest2=def_elevs(nelev);
        else
            ele_closest2=def_elevs(nelev+1);
        end
    else
        if nelev==1
            ele_closest2=def_elevs(nelev);
        else            
            ele_closest2=def_elevs(nelev-1);
        end
    end
    
    %Calculating closest azimuth samples
    def_azi= [ -80 -65 -55 -45:5:45 55 65 80 ];
    [azi_closest1,naz]=min(abs(pvaldeg(abs(def_azi-azimuth))));    % naz=Index number of selected azimuth
    azi_closest1=def_azi(naz);                                     % final selected azimuth
    if azi_closest1<azimuth
        if naz==25
            azi_closest2=def_azi(naz);
        else
            azi_closest2=def_azi(naz+1);
        end
    else
        if naz==1
            azi_closest2=def_azi(naz);
        else
            azi_closest2=def_azi(naz-1);
        end
    end
    
    %% Now we have the 2 closest azimuth and elevation neighbours for which the impulse response is known.
    %% For bilinear interpolation, first we keep elevation constant and interpolate in azimuth direction 
    load ('hrir_final.mat',"hrir_l");
    load("hrir_final.mat","hrir_r");
    h_l_az1=squeeze(hrir_l(naz,nelev,:));   %% First interpolating in the direction of azimuth
    h_r_az1=squeeze(hrir_r(naz,nelev,:));  
    if azi_closest1<azimuth
        if naz==25
            h_l_az2=squeeze(hrir_l(naz,nelev,:));
            h_r_az2=squeeze(hrir_l(naz,nelev,:));
        else
            h_l_az2=squeeze(hrir_l(naz+1,nelev,:));
            h_r_az2=squeeze(hrir_l(naz+1,nelev,:));
        end
            h_l_az= h_l_az2 * ((azimuth-azi_closest1)/(azi_closest2-azi_closest1)) + h_l_az1 * ((azi_closest2-azimuth)/(azi_closest2-azi_closest1));
            h_r_az= h_r_az2 * ((azimuth-azi_closest1)/(azi_closest2-azi_closest1)) + h_r_az1 * ((azi_closest2-azimuth)/(azi_closest2-azi_closest1));
    else
        if naz==1
          h_l_az2=squeeze(hrir_l(naz,nelev,:));
          h_r_az2=squeeze(hrir_r(naz,nelev,:));
        else
          h_l_az2=squeeze(hrir_l(naz-1,nelev,:));
          h_r_az2=squeeze(hrir_r(naz-1,nelev,:)); 
        end
        h_l_az= h_l_az2 * ((azi_closest1-azimuth)/(azi_closest1-azi_closest2)) + h_l_az1 * ((azimuth-azi_closest2)/(azi_closest1-azi_closest2));
        h_r_az= h_r_az2 * ((azi_closest1-azimuth)/(azi_closest1-azi_closest2)) + h_r_az1 * ((azimuth-azi_closest2)/(azi_closest1-azi_closest2));
    end
    %% now interpolating in the direction of elevation keeping azimuth constant
    h_l_el1=squeeze(hrir_l(naz,nelev,:));  
    h_r_el1=squeeze(hrir_r(naz,nelev,:));
    if ele_closest1<elevation
        if nelev==50
            h_l_el2=squeeze(hrir_l(naz,nelev,:));
            h_r_el2=squeeze(hrir_r(naz,nelev,:));
        else
            h_l_el2=squeeze(hrir_l(naz,nelev+1,:));
            h_r_el2=squeeze(hrir_r(naz,nelev+1,:));
        end
            h_l_el= h_l_el2 * ((elevation-ele_closest1)/(ele_closest2-ele_closest1)) + h_l_el1 * ((ele_closest2-elevation)/(ele_closest2-ele_closest1));
            h_r_el= h_r_el2 * ((elevation-ele_closest1)/(ele_closest2-ele_closest1)) + h_r_el1 * ((ele_closest2-elevation)/(ele_closest2-ele_closest1));
    else
        if nelev==1
          h_l_el2=squeeze(hrir_l(naz,nelev,:));  
          h_r_el2=squeeze(hrir_r(naz,nelev,:));  
        else
          h_l_el2=squeeze(hrir_l(naz,nelev-1,:)); 
          h_r_el2=squeeze(hrir_r(naz,nelev-1,:)); 
        end
        h_l_el= h_l_el2 * ((ele_closest1-elevation)/(ele_closest1-ele_closest2)) + h_l_el1 * ((elevation-ele_closest2)/(ele_closest1-ele_closest2));
        h_r_el= h_r_el2 * ((ele_closest1-elevation)/(ele_closest1-ele_closest2)) + h_r_el1 * ((elevation-ele_closest2)/(ele_closest1-ele_closest2));
    end
    %% Calculating final HRIR for Left Ear
    h_l=(h_l_az + h_l_el)/2;
    subplot(3,1,2);
    stem(h_l);
    title("Left HRIR");
    y_l=filter(h_l,1,y);       % convolution using filter
    % Calculating final HRIR for Right Ear
    h_r=(h_r_az + h_r_el)/2;
    subplot(3,1,3);
    stem(h_r);
    title("right HRIR");
    y_r=filter(h_r,1,y);       % convolution using filter
    
    %% Final audio output
    y_final=[y_l;y_r];
    sound(y_final,fs);
    pause(5);
    clear sound;
        
end

function [y]=fconv(x, h)

Ly=length(x)+length(h)-1;  % 
Ly2=pow2(nextpow2(Ly));    % Find smallest power of 2 that is > Ly
X=fft(x, Ly2);		       % Fast Fourier transform
H=fft(h, Ly2);	           % Fast Fourier transform
Y=X.*H;        	           % 
y=real(ifft(Y, Ly2));      % Inverse fast Fourier transform
y=y(1:1:Ly);               % Take just the first N elements
y=y/max(abs(y));           % Normalize the output
end
    
            
        end

        % Value changing function: X1Slider
        function X1SliderValueChanging(app, event)
             changingValue = event.Value;
             app.s1x=changingValue;
             x=[app.s1x app.s4x];
            y=[app.s1y app.s4y];
            z=[app.el1z app.el4z];
            plot(app.UIAxes_2,x,y,"Marker","o","MarkerSize",10,"LineStyle","none");
            plot3(app.UIAxes,x,y,z,"Marker","o","MarkerSize",10,"LineStyle","none");
        end

        % Value changing function: Y1Slider
        function Y1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.s1y=changingValue;
             x=[app.s1x app.s4x];
            y=[app.s1y app.s4y];
            z=[app.el1z app.el4z];
            plot(app.UIAxes_2,x,y,"Marker","o","MarkerSize",10,"LineStyle","none");
            plot3(app.UIAxes,x,y,z,"Marker","o","MarkerSize",10,"LineStyle","none");
        end

        % Value changing function: ListenerElevationSlider
        function ListenerElevationSliderValueChanging(app, event)
            changingValue = event.Value;
            app.el4z=changingValue;
           x=[app.s1x app.s4x];
            y=[app.s1y app.s4y];
            z=[app.el1z app.el4z];
            %plot(app.UIAxes2,x,y,"Marker","o","MarkerSize",10,"LineStyle","none");
            plot3(app.UIAxes,x,y,z,"Marker","o","MarkerSize",10,"LineStyle","none");
        end

        % Value changing function: El1Slider
        function El1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.el1z=changingValue;
           x=[app.s1x app.s4x];
            y=[app.s1y app.s4y];
            z=[app.el1z app.el4z];
            plot3(app.UIAxes,x,y,z,"Marker","o","MarkerSize",10,"LineStyle","none");
        end

        % Button pushed function: ListenerTrajectoryButton
        function ListenerTrajectoryButtonPushed(app, event)
          % [x, y] = selectDataPoints(app, app.UIAxes); 
             app.roi = drawpolyline(app.UIAxes_2,'Color','blue');
            app.coordinates=app.roi.Position;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GridTab = uitab(app.TabGroup);
            app.GridTab.Title = 'Grid';
            app.UIAxes_2 = uiaxes(app.GridTab);
            title(app.UIAxes_2, 'Room Plot')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [-1 1];
            app.UIAxes_2.YLim = [-1 1];
            app.UIAxes_2.ZLim = [0 1];
            app.UIAxes_2.XTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2.YTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2.ZTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.SoundsourceLabel = uilabel(app.GridTab);
            app.SoundsourceLabel.Text = '     Sound source';
            app.ListenerCoordinatesLabel = uilabel(app.GridTab);
            app.ListenerCoordinatesLabel.Text = 'Listener Coordinates';
            app.X1SliderLabel = uilabel(app.GridTab);
            app.X1SliderLabel.Text = 'X1';
            app.X1Slider = uislider(app.GridTab);
            app.X1Slider.Limits = [-1 1];
            app.X1Slider.ValueChangingFcn = createCallbackFcn(app, @X1SliderValueChanging, true);
            app.X1Slider.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Y1SliderLabel = uilabel(app.GridTab);
            app.Y1SliderLabel.Text = 'Y1';
            app.Y1Slider = uislider(app.GridTab);
            app.Y1Slider.Limits = [-1 1];
            app.Y1Slider.ValueChangingFcn = createCallbackFcn(app, @Y1SliderValueChanging, true);
            app.PLAYButton = uibutton(app.GridTab, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.ListenerTrajectoryButton = uibutton(app.GridTab, 'push');
            app.ListenerTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ListenerTrajectoryButtonPushed, true);
            app.ListenerTrajectoryButton.Text = 'Listener Trajectory';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Room Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-1 1];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.ZTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.ElevationsLabel = uilabel(app.Tab);
            app.ElevationsLabel.Text = '     Elevations';
            app.El1Slider = uislider(app.Tab);
            app.El1Slider.Limits = [0 1];
            app.El1Slider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.El1Slider.ValueChangingFcn = createCallbackFcn(app, @El1SliderValueChanging, true);
            app.ListenerElevationSlider = uislider(app.Tab);
            app.ListenerElevationSlider.Limits = [0 1];
            app.ListenerElevationSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ListenerElevationSlider.ValueChangingFcn = createCallbackFcn(app, @ListenerElevationSliderValueChanging, true);
            app.ListenerElevationSlider.MinorTicks = [];
            app.SoundSrcElevLabel = uilabel(app.Tab);
            app.SoundSrcElevLabel.Text = 'Sound Src Elev';
            app.ListenerElevLabel = uilabel(app.Tab);
            app.ListenerElevLabel.Text = 'Listener Elev';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Latest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end